classdef tutorial_plot < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure  matlab.ui.Figure
        UIAxes    matlab.ui.control.UIAxes
        DropDown  matlab.ui.control.DropDown
    end


    properties (Access = private)
        xaxis; % Description
        yaxis;
    end

    methods (Access = private)
    
        function [ ] = checkDrop(app)
           
            value = app.DropDown.Value;
            switch     value
                case    'Line Plot'  
                    plot(app.UIAxes,app.xaxis,app.yaxis);
                case    'Scatter Plot'
                    scatter(app.UIAxes,app.xaxis,app.yaxis);
                case    'Scatter and Line Plot'
                    plot(app.UIAxes,app.xaxis,app.yaxis);
                    hold(app.UIAxes,'on');
                    scatter(app.UIAxes,app.xaxis,app.yaxis);
                    hold(app.UIAxes, 'off'); 
            end 
        end       %end checkDrop(app)
        
    end


    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.xaxis = linspace(0,10*pi,10);
            app.yaxis = sin(app.xaxis);
            disp( size( app.xaxis ));
           
            %%%  debug
                 app.UIAxes.XLim = [0,max(app.xaxis)+1]
                %app.UIAxes.XLimMode = 'auto';
                app.UIAxes.XTick = app.xaxis;
                
                app.UIAxes.YLim = [min(app.yaxis)-1,max(app.yaxis)+1];
                app.UIAxes.YTick = sort(app.yaxis); 
                %app.UIAxes.YLimMode = 'auto';
            %%%
            
            checkDrop(app);    % refresh graph from drop down selection
        end

        % Value changed function: DropDown
        function DropDownValueChanged(app, event)
            value = app.DropDown.Value;
            event.Value;
            
            checkDrop(app);
            switch     value
                case    'Line Plot'  
                    plot(app.UIAxes,app.xaxis,app.yaxis);
                case    'Scatter Plot'
                    scatter(app.UIAxes,app.xaxis,app.yaxis);
                case    'Scatter and Line Plot'
                    plot(app.UIAxes,app.xaxis,app.yaxis);
                    hold(app.UIAxes,'on');
                    scatter(app.UIAxes,app.xaxis,app.yaxis);
                    hold(app.UIAxes, 'off'); 
            end 
              
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure;
            app.UIFigure.Name = 'UI Figure';
            app.UIAxes = uiaxes(app.UIFigure);
            app.UIAxes.XLim = [0 1];
            app.UIAxes.XLimMode = 'manual';
            app.UIAxes.YLim = [0 1];
            app.UIAxes.YLimMode = 'manual';
            app.UIAxes.CLim = [0 1];
            app.UIAxes.CLimMode = 'manual';
            app.DropDown = uidropdown(app.UIFigure);
            app.DropDown.Items = {'Line Plot', 'Scatter Plot', 'Scatter and Line Plot'};
            app.DropDown.ValueChangedFcn = createCallbackFcn(app, @DropDownValueChanged, true);
            app.DropDown.Value = 'Line Plot';
        end
    end

    methods (Access = public)

        % Construct app
        function app = tutorial_plot

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end